classdef ihm < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        Panel                        matlab.ui.container.Panel
        GeneratethemosaicButton      matlab.ui.control.Button
        ColorCorrectCheckBox         matlab.ui.control.CheckBox
        BlendingStrengthSlider       matlab.ui.control.Slider
        BlendingStrengthSliderLabel  matlab.ui.control.Label
        MatchingDropDown             matlab.ui.control.DropDown
        MatchingDropDownLabel        matlab.ui.control.Label
        ValidatetilessizeButton      matlab.ui.control.Button
        TilessizeSlider              matlab.ui.control.Slider
        TilessizeSliderLabel         matlab.ui.control.Label
        LoadthedatasetButton         matlab.ui.control.Button
        LoadthetargetimageButton     matlab.ui.control.Button
        MOSAICAPPLabel               matlab.ui.control.Label
        HelpButton                   matlab.ui.control.Button
        UIAxes                       matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        ImageTarget % Stores the original image uploaded by the user
        TileLibrary % Stores resized tiles ready for mosaic
        Mosaic_Result 
        RawTiles %Contains the raw images loaded by loadTileDataset
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
        end

        % Button pushed function: LoadthetargetimageButton
        function LoadthetargetimageButtonPushed(app, event)
            try
                app.ImageTarget=loadTargetImage();
                imshow(app.ImageTarget, 'Parent', app.UIAxes);
                title(app.UIAxes, 'Target image successfully loaded !');

                catch ME
       
                    uialert(app.UIFigure, ['Statut : ' ME.message],'Image selection', 'Icon', 'info');
            end
        end

        % Button pushed function: LoadthedatasetButton
        function LoadthedatasetButtonPushed(app, event)
            file_load=uigetdir('', 'Select the folder containing the tiles');
            if file_load~=0
                app.RawTiles=loadTileDataset(file_load);
                uialert(app.UIFigure, 'The dataset was successfully loaded !', 'Statut','Icon', 'success');
            end
        end

        % Callback function
        function TilessizeSliderValueChanged(app, event)
         
        end

        % Button pushed function: ValidatetilessizeButton
        function ValidatetilessizeButtonPushed(app, event)
            if isempty(app.RawTiles)
                uialert(app.UIFigure, 'Please load a dataset first!', ...
                'Missing data', 'Icon', 'warning');
                return;
            end
            N=round(app.TilessizeSlider.Value);

            tileSize = [N, N];
            title(app.UIAxes, 'Tile resizing in progress...');
            drawnow;
        
            try
                app.TileLibrary = resizeTiles(app.RawTiles, tileSize);
                uialert(app.UIFigure, sprintf('All tiles have been resized to %dx%d pixels!', N, N), ...
                'Successful operation', 'Icon', 'success');
                title(app.UIAxes, 'Tiles ready for mosaic');

            catch ME 
                uialert(app.UIFigure, ['Error during resizing: ' ME.message], 'Erreur');
                title(app.UIAxes, 'Error during resizing');
            end

        end

        % Button pushed function: GeneratethemosaicButton
        function GeneratethemosaicButtonPushed(app, event)
            if isempty(app.ImageTarget) || isempty(app.TileLibrary)
                uialert(app.UIFigure, 'Please load the target image and dataset before generating !', ...
                'Missing data', 'Icon', 'warning');
                return;
            end

            N= round(app.TilessizeSlider.Value);
            tileSize=[N,N];
            method=app.MatchingDropDown.Value;

            opts=struct();
            opts.nBins=8;
            opts.weights=[1 1 1];

            opts.colorCorrect = app.ColorCorrectCheckBox.Value;
            opts.blendAlpha = app.BlendingStrengthSlider.Value;

            title(app.UIAxes, ['Calculation in progress using the method : ', method, '...']);
            drawnow;

            try
                app.Mosaic_Result = generateMosaic(app.ImageTarget, app.TileLibrary, tileSize, method, opts);
                
                imshow(app.Mosaic_Result, 'Parent', app.UIAxes);
                title(app.UIAxes, 'Mosaic finished ! Method: ', method);

            catch ME
                uialert(app.UIFigure, ['Error during generation : ' ME.message], 'Error');
                title(app.UIAxes, 'Generation failed');
            end


        end

        % Button pushed function: HelpButton
        function HelpButtonPushed(app, event)
           
            helpMessage = { ...
                'MOSAIC APP - USER GUIDE', ...
                '', ...
                'Follow these 4 simple steps to generate your mosaic:', ...
                '', ...
                '1. LOAD THE TARGET IMAGE', ...
                '   Click "Load the target image" to select the high-resolution background picture.', ...
                '', ...
                '2. LOAD THE DATASET', ...
                '   Click "Load the dataset" and select the folder containing your tile images.', ...
                '', ...
                '3. SET & VALIDATE TILE SIZE', ...
                '   Adjust the "Tiles size" slider (16 to 64px) and click "Validate tiles size".', ...
                '', ...
                '4. CHOOSE SETTINGS & GENERATE', ...
                '   - Select your matching strategy in the "Matching" dropdown.', ...
                '   - Check "Color Correct" to enable color adjustments.', ...
                '   - Adjust "Blending Strength" to blend the original image with alpha transparency.', ...
                '   Click "Generate the mosaic" to view the final result!', ...
                '', ...
               
            };
            
            % Affichage de la boîte de dialogue d'aide
            msgbox(helpMessage, 'Application Instructions');
     
        end

        % Size changed function: UIFigure
        function UIFigureSizeChanged(app, event)
            figPos = app.UIFigure.Position;
            figWidth = figPos(3);
            
            % 2. Trouver le nom exact de ton panel à droite (ex: app.Panel)
            % On change juste sa coordonnée X pour le coller à droite
            panelWidth = app.Panel.Position(3); % Récupère sa largeur actuelle
            
            % Aligne le panel à droite avec 20 pixels de marge
            app.Panel.Position(1) = figWidth - panelWidth - 20;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @UIFigureSizeChanged, true);
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Mosaic viewer')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.HelpButton = uibutton(app.UIFigure, 'push');
            app.HelpButton.ButtonPushedFcn = createCallbackFcn(app, @HelpButtonPushed, true);
            app.HelpButton.Text = 'Help';
            app.MOSAICAPPLabel = uilabel(app.UIFigure);
            app.MOSAICAPPLabel.Text = 'MOSAIC APP';
            app.Panel = uipanel(app.UIFigure);
            app.LoadthetargetimageButton = uibutton(app.Panel, 'push');
            app.LoadthetargetimageButton.ButtonPushedFcn = createCallbackFcn(app, @LoadthetargetimageButtonPushed, true);
            app.LoadthetargetimageButton.Text = 'Load the target image ';
            app.LoadthedatasetButton = uibutton(app.Panel, 'push');
            app.LoadthedatasetButton.ButtonPushedFcn = createCallbackFcn(app, @LoadthedatasetButtonPushed, true);
            app.LoadthedatasetButton.Text = 'Load the dataset';
            app.TilessizeSliderLabel = uilabel(app.Panel);
            app.TilessizeSliderLabel.Text = 'Tiles size';
            app.TilessizeSlider = uislider(app.Panel);
            app.TilessizeSlider.Limits = [16 64];
            app.TilessizeSlider.MajorTicks = [16 32 48 64];
            app.TilessizeSlider.MinorTicks = [16 32 48 64];
            app.TilessizeSlider.Value = 32;
            app.ValidatetilessizeButton = uibutton(app.Panel, 'push');
            app.ValidatetilessizeButton.ButtonPushedFcn = createCallbackFcn(app, @ValidatetilessizeButtonPushed, true);
            app.ValidatetilessizeButton.Text = 'Validate tiles size';
            app.MatchingDropDownLabel = uilabel(app.Panel);
            app.MatchingDropDownLabel.Text = 'Matching';
            app.MatchingDropDown = uidropdown(app.Panel);
            app.MatchingDropDown.Items = {'mean_rgb', 'luminance', 'texture', 'histogram_l2', 'histogram_chi2', 'histogram_intersection', 'histogram_bhattacharyya', 'combined'};
            app.MatchingDropDown.Value = 'mean_rgb';
            app.BlendingStrengthSliderLabel = uilabel(app.Panel);
            app.BlendingStrengthSliderLabel.Text = 'Blending Strength';
            app.BlendingStrengthSlider = uislider(app.Panel);
            app.BlendingStrengthSlider.Limits = [0 1];
            app.BlendingStrengthSlider.MajorTicks = [0 0.25 0.5 0.75 1];
            app.ColorCorrectCheckBox = uicheckbox(app.Panel);
            app.ColorCorrectCheckBox.Text = 'Color Correct';
            app.GeneratethemosaicButton = uibutton(app.Panel, 'push');
            app.GeneratethemosaicButton.ButtonPushedFcn = createCallbackFcn(app, @GeneratethemosaicButtonPushed, true);
            app.GeneratethemosaicButton.Text = 'Generate the mosaic';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ihm

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end