classdef ihm < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        BlendingStrengthSlider       matlab.ui.control.Slider
        BlendingStrengthSliderLabel  matlab.ui.control.Label
        ColorCorrectCheckBox         matlab.ui.control.CheckBox
        ValidatetilessizeButton      matlab.ui.control.Button
        LoadthedatasetButton         matlab.ui.control.Button
        GeneratethemosaicButton      matlab.ui.control.Button
        MatchingDropDown             matlab.ui.control.DropDown
        MatchingDropDownLabel        matlab.ui.control.Label
        TilessizeSlider              matlab.ui.control.Slider
        TilessizeSliderLabel         matlab.ui.control.Label
        LoadthetargetimageButton     matlab.ui.control.Button
        UIAxes                       matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        ImageTarget % Stores the original image uploaded by the user
        TileLibrary % Stores resized tiles ready for mosaic
        Mosaic_Result 
        RawTiles %Contains the raw images loaded by loadTileDataset
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
        end

        % Button pushed function: LoadthetargetimageButton
        function LoadthetargetimageButtonPushed(app, event)
            try
                app.ImageTarget=loadTargetImage();
                imshow(app.ImageTarget, 'Parent', app.UIAxes);
                title(app.UIAxes, 'Target image successfully loaded !');

                catch ME
       
                    uialert(app.UIFigure, ['Statut : ' ME.message],'Image selection', 'Icon', 'info');
            end
        end

        % Button pushed function: LoadthedatasetButton
        function LoadthedatasetButtonPushed(app, event)
            file_load=uigetdir('', 'Select the folder containing the tiles');
            if file_load~=0
                app.RawTiles=loadTileDataset(file_load);
                uialert(app.UIFigure, 'The dataset was successfully loaded. !', 'Statut');
            end
        end

        % Callback function: not associated with a component
        function TilessizeSliderValueChanged(app, event)
         
        end

        % Button pushed function: ValidatetilessizeButton
        function ValidatetilessizeButtonPushed(app, event)
            if isempty(app.RawTiles)
                uialert(app.UIFigure, 'Please load a dataset first!', ...
                'Missing data', 'Icon', 'warning');
                return;
            end
            N=round(app.TilessizeSlider.Value);

            tileSize = [N, N];
            title(app.UIAxes, 'Tile resizing in progress...');
            drawnow;
        
            try
                app.TileLibrary = resizeTiles(app.RawTiles, tileSize);
                uialert(app.UIFigure, sprintf('All tiles have been resized to %dx%d pixels!', N, N), ...
                'Successful operation', 'Icon', 'success');
                title(app.UIAxes, 'Tiles ready for mosaic');

            catch ME 
                uialert(app.UIFigure, ['Erreur de redimensionnement : ' ME.message], 'Erreur');
                title(app.UIAxes, 'Error during resizing');
            end

        end

        % Button pushed function: GeneratethemosaicButton
        function GeneratethemosaicButtonPushed(app, event)
            if isempty(app.ImageTarget) || isempty(app.TileLibrary)
                uialert(app.UIFigure, 'Please load the target image and dataset before generating !', ...
                'Missing data', 'Icon', 'warning');
                return;
            end

            N= app.TilessizeSlider.Value;
            tileSize=[N,N];
            method=app.MatchingDropDown.Value;

            opts=struct();
            opts.nBins=8;
            opts.weights=[1 1 1];

            opts.colorCorrect = app.ColorCorrectCheckBox.Value;
            opts.blendAlpha = app.BlendingStrengthSlider.Value;

            title(app.UIAxes, ['Calculation in progress using the method : ', method, '...']);
            drawnow;

            try
                app.Mosaic_Result = generateMosaic(app.ImageTarget, app.TileLibrary, tileSize, method, opts);
                
                imshow(app.Mosaic_Result, 'Parent', app.UIAxes);
                title(app.UIAxes, 'Mosaic finished ! Method: ', method);

            catch ME
                uialert(app.UIFigure, ['Erreur lors de la génération : ' ME.message], 'Erreur de calcul');
                title(app.UIAxes, 'Generation failed');
            end


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Mosaic viewer')
            app.LoadthetargetimageButton = uibutton(app.UIFigure, 'push');
            app.LoadthetargetimageButton.ButtonPushedFcn = createCallbackFcn(app, @LoadthetargetimageButtonPushed, true);
            app.LoadthetargetimageButton.Text = 'Load the target image ';
            app.TilessizeSliderLabel = uilabel(app.UIFigure);
            app.TilessizeSliderLabel.Text = 'Tiles size';
            app.TilessizeSlider = uislider(app.UIFigure);
            app.TilessizeSlider.Limits = [16 64];
            app.TilessizeSlider.MajorTicks = [16 32 48 64];
            app.TilessizeSlider.MinorTicks = [16 32 48 64];
            app.TilessizeSlider.Value = 32;
            app.MatchingDropDownLabel = uilabel(app.UIFigure);
            app.MatchingDropDownLabel.Text = 'Matching';
            app.MatchingDropDown = uidropdown(app.UIFigure);
            app.MatchingDropDown.Items = {'mean_rgb', 'luminance', 'texture', 'histogram_l2', 'combined'};
            app.MatchingDropDown.Value = 'mean_rgb';
            app.GeneratethemosaicButton = uibutton(app.UIFigure, 'push');
            app.GeneratethemosaicButton.ButtonPushedFcn = createCallbackFcn(app, @GeneratethemosaicButtonPushed, true);
            app.GeneratethemosaicButton.Text = 'Generate the mosaic';
            app.LoadthedatasetButton = uibutton(app.UIFigure, 'push');
            app.LoadthedatasetButton.ButtonPushedFcn = createCallbackFcn(app, @LoadthedatasetButtonPushed, true);
            app.LoadthedatasetButton.Text = 'Load the dataset';
            app.ValidatetilessizeButton = uibutton(app.UIFigure, 'push');
            app.ValidatetilessizeButton.ButtonPushedFcn = createCallbackFcn(app, @ValidatetilessizeButtonPushed, true);
            app.ValidatetilessizeButton.Text = 'Validate tiles size';
            app.ColorCorrectCheckBox = uicheckbox(app.UIFigure);
            app.ColorCorrectCheckBox.Text = 'Color Correct';
            app.BlendingStrengthSliderLabel = uilabel(app.UIFigure);
            app.BlendingStrengthSliderLabel.Text = 'Blending Strength';
            app.BlendingStrengthSlider = uislider(app.UIFigure);
            app.BlendingStrengthSlider.Limits = [0 1];
            app.BlendingStrengthSlider.MajorTicks = [0 0.25 0.5 0.75 1];
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ihm

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end