classdef mosaic_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GeneratethemosaicButton         matlab.ui.control.Button
        Panel                           matlab.ui.container.Panel
        Step1LOADTHETARGETIMAGELabel_16  matlab.ui.control.Label
        Step1LOADTHETARGETIMAGELabel_14  matlab.ui.control.Label
        Step1LOADTHETARGETIMAGELabel_13  matlab.ui.control.Label
        Step1LOADTHETARGETIMAGELabel_12  matlab.ui.control.Label
        Step1LOADTHETARGETIMAGELabel_11  matlab.ui.control.Label
        Step1LOADTHETARGETIMAGELabel_10  matlab.ui.control.Label
        Step1LOADTHETARGETIMAGELabel_9  matlab.ui.control.Label
        Step1LOADTHETARGETIMAGELabel_8  matlab.ui.control.Label
        Step1LOADTHETARGETIMAGELabel_7  matlab.ui.control.Label
        Step1LOADTHETARGETIMAGELabel_6  matlab.ui.control.Label
        Step1LOADTHETARGETIMAGELabel_5  matlab.ui.control.Label
        Step1LOADTHETARGETIMAGELabel_4  matlab.ui.control.Label
        Step1LOADTHETARGETIMAGELabel_3  matlab.ui.control.Label
        Step1LOADTHETARGETIMAGELabel_2  matlab.ui.control.Label
        Step1LOADTHETARGETIMAGELabel    matlab.ui.control.Label
        ColorCorrectCheckBox            matlab.ui.control.CheckBox
        BlendingStrengthSlider          matlab.ui.control.Slider
        BlendingStrengthSliderLabel     matlab.ui.control.Label
        MatchingDropDown                matlab.ui.control.DropDown
        MatchingDropDownLabel           matlab.ui.control.Label
        ValidatetilessizeButton         matlab.ui.control.Button
        TilessizeSlider                 matlab.ui.control.Slider
        TilessizeSliderLabel            matlab.ui.control.Label
        LoadthedatasetButton            matlab.ui.control.Button
        LoadthetargetimageButton        matlab.ui.control.Button
        MOSAICAPPLabel                  matlab.ui.control.Label
        UIAxes                          matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        ImageTarget % Stores the original image uploaded by the user
        TileLibrary % Stores resized tiles ready for mosaic
        Mosaic_Result 
        RawTiles %Contains the raw images loaded by loadTileDataset
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            addpath('functions');
            app.UIFigure.SizeChangedFcn = @(src, event) UIFigureSizeChanged(app, []);
            drawnow;
            UIFigureSizeChanged(app, []);
        end

        % Button pushed function: LoadthetargetimageButton
        function LoadthetargetimageButtonPushed(app, event)
            try
                app.ImageTarget=loadTargetImage();
                figure(app.UIFigure);
                imshow(app.ImageTarget, 'Parent', app.UIAxes);
                title(app.UIAxes, 'Target image successfully loaded !');

                catch ME
                    figure(app.UIFigure);
                    uialert(app.UIFigure, ['Statut : ' ME.message],'Image selection', 'Icon', 'info');
            end
        end

        % Button pushed function: LoadthedatasetButton
        function LoadthedatasetButtonPushed(app, event)
            file_load=uigetdir('', 'Select the folder containing the tiles');
            if file_load~=0
                app.RawTiles=loadTileDataset(file_load);
                figure(app.UIFigure);
                uialert(app.UIFigure, 'The dataset was successfully loaded !', 'Statut','Icon', 'success');
            end
        end

        % Callback function
        function TilessizeSliderValueChanged(app, event)
         
        end

        % Button pushed function: ValidatetilessizeButton
        function ValidatetilessizeButtonPushed(app, event)
            if isempty(app.RawTiles)
                uialert(app.UIFigure, 'Please load a dataset first!', ...
                'Missing data', 'Icon', 'warning');
                return;
            end
            N=round(app.TilessizeSlider.Value);

            tileSize = [N, N];
            d = uiprogressdlg(app.UIFigure, 'Title', 'Please Wait', ...
                'Message', 'Resizing tiles...', 'Cancelable', 'off');
            drawnow;
        
            try
                app.TileLibrary = resizeTiles(app.RawTiles, tileSize,d);
                uialert(app.UIFigure, sprintf('All tiles have been resized to %dx%d pixels!', N, N), ...
                'Successful operation', 'Icon', 'success');
                title(app.UIAxes, 'Tiles ready for mosaic');

            catch ME 
                if exist('d', 'var') && isvalid(d), close(d); end
                uialert(app.UIFigure, ['Error during resizing: ' ME.message], 'Erreur');
                title(app.UIAxes, 'Error during resizing');
            end

        end

        % Callback function
        function GeneratethemosaicButtonPushed(app, event)
            if isempty(app.ImageTarget) || isempty(app.TileLibrary)
                uialert(app.UIFigure, 'Please load the target image and dataset before generating !', ...
                'Missing data', 'Icon', 'warning');
                return;
            end

            N= round(app.TilessizeSlider.Value);
            tileSize=[N,N];
            method=app.MatchingDropDown.Value;

            opts=struct();
            opts.nBins=8;
            opts.weights=[1 1 1];

            opts.colorCorrect = app.ColorCorrectCheckBox.Value;
            opts.blendAlpha = app.BlendingStrengthSlider.Value;
       

            d = uiprogressdlg(app.UIFigure, 'Title', 'Generating Mosaic', ...
                'Message', ['Matching tiles using ' method '...'], ...
                'Indeterminate', 'on', 'Cancelable', 'off');
            drawnow;

            try
                app.Mosaic_Result = generateMosaic(app.ImageTarget, app.TileLibrary, tileSize, method, opts);
                
                close(d);
                imshow(app.Mosaic_Result, 'Parent', app.UIAxes);
                title(app.UIAxes, ['Mosaic finished ! Method: ', method]);

            catch ME
                if exist('d', 'var') && isvalid(d), close(d); end
                uialert(app.UIFigure, ['Error during generation : ' ME.message], 'Error');
                title(app.UIAxes, 'Generation failed');
            end

        end

        % Callback function
        function HelpButtonPushed(app, event)
           
     
        end

        % Callback function
        function UIFigureSizeChanged(app, event)

        end

        % Size changed function: UIFigure
        function UIFigureSizeChanged2(app, event)
            
        end

        % Button pushed function: GeneratethemosaicButton
        function GeneratethemosaicButtonPushed2(app, event)
             if isempty(app.ImageTarget) || isempty(app.TileLibrary)
                uialert(app.UIFigure, 'Please load the target image and dataset before generating !', ...
                'Missing data', 'Icon', 'warning');
                return;
            end

            N= round(app.TilessizeSlider.Value);
            tileSize=[N,N];
            method=app.MatchingDropDown.Value;

            opts=struct();
            opts.nBins=8;
            opts.weights=[1 1 1];

            opts.colorCorrect = app.ColorCorrectCheckBox.Value;
            opts.blendAlpha = app.BlendingStrengthSlider.Value;
       

            d = uiprogressdlg(app.UIFigure, 'Title', 'Generating Mosaic', ...
                'Message', ['Matching tiles using ' method '...'], ...
                'Indeterminate', 'on', 'Cancelable', 'off');
            drawnow;

            try
                app.Mosaic_Result = generateMosaic(app.ImageTarget, app.TileLibrary, tileSize, method, opts);
                
                close(d);
                imshow(app.Mosaic_Result, 'Parent', app.UIAxes);
                title(app.UIAxes, ['Mosaic finished ! Method: ', method]);

            catch ME
                if exist('d', 'var') && isvalid(d), close(d); end
                uialert(app.UIFigure, ['Error during generation : ' ME.message], 'Error');
                title(app.UIAxes, 'Generation failed');
            end

     
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @UIFigureSizeChanged2, true);
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Mosaic viewer')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.MOSAICAPPLabel = uilabel(app.UIFigure);
            app.MOSAICAPPLabel.Text = 'MOSAIC APP';
            app.Panel = uipanel(app.UIFigure);
            app.LoadthetargetimageButton = uibutton(app.Panel, 'push');
            app.LoadthetargetimageButton.ButtonPushedFcn = createCallbackFcn(app, @LoadthetargetimageButtonPushed, true);
            app.LoadthetargetimageButton.Text = 'Load the target image ';
            app.LoadthedatasetButton = uibutton(app.Panel, 'push');
            app.LoadthedatasetButton.ButtonPushedFcn = createCallbackFcn(app, @LoadthedatasetButtonPushed, true);
            app.LoadthedatasetButton.Text = 'Load the dataset';
            app.TilessizeSliderLabel = uilabel(app.Panel);
            app.TilessizeSliderLabel.Text = 'Tiles size';
            app.TilessizeSlider = uislider(app.Panel);
            app.TilessizeSlider.Limits = [16 64];
            app.TilessizeSlider.MajorTicks = [16 32 48 64];
            app.TilessizeSlider.MinorTicks = [16 32 48 64];
            app.TilessizeSlider.Value = 32;
            app.ValidatetilessizeButton = uibutton(app.Panel, 'push');
            app.ValidatetilessizeButton.ButtonPushedFcn = createCallbackFcn(app, @ValidatetilessizeButtonPushed, true);
            app.ValidatetilessizeButton.Text = 'Validate tiles size';
            app.MatchingDropDownLabel = uilabel(app.Panel);
            app.MatchingDropDownLabel.Text = 'Matching';
            app.MatchingDropDown = uidropdown(app.Panel);
            app.MatchingDropDown.Items = {'mean_rgb', 'luminance', 'texture', 'histogram_bhattacharyya', 'combined'};
            app.MatchingDropDown.Value = 'mean_rgb';
            app.BlendingStrengthSliderLabel = uilabel(app.Panel);
            app.BlendingStrengthSliderLabel.Text = 'Blending Strength';
            app.BlendingStrengthSlider = uislider(app.Panel);
            app.BlendingStrengthSlider.Limits = [0 1];
            app.BlendingStrengthSlider.MajorTicks = [0 0.25 0.5 0.75 1];
            app.ColorCorrectCheckBox = uicheckbox(app.Panel);
            app.ColorCorrectCheckBox.Text = 'Color Correct';
            app.Step1LOADTHETARGETIMAGELabel = uilabel(app.Panel);
            app.Step1LOADTHETARGETIMAGELabel.Text = 'Step 1 :  Click "Load the target image" to';
            app.Step1LOADTHETARGETIMAGELabel_2 = uilabel(app.Panel);
            app.Step1LOADTHETARGETIMAGELabel_2.Text = 'select the background picture.';
            app.Step1LOADTHETARGETIMAGELabel_3 = uilabel(app.Panel);
            app.Step1LOADTHETARGETIMAGELabel_3.Text = 'Step 2 :  Click "Load the dataset" and ';
            app.Step1LOADTHETARGETIMAGELabel_4 = uilabel(app.Panel);
            app.Step1LOADTHETARGETIMAGELabel_4.Text = ' select the folder containing your tile';
            app.Step1LOADTHETARGETIMAGELabel_5 = uilabel(app.Panel);
            app.Step1LOADTHETARGETIMAGELabel_5.Text = 'containing your tile images.';
            app.Step1LOADTHETARGETIMAGELabel_6 = uilabel(app.Panel);
            app.Step1LOADTHETARGETIMAGELabel_6.Text = 'Step 3 :  Adjust the "Tiles size" slider and  ';
            app.Step1LOADTHETARGETIMAGELabel_7 = uilabel(app.Panel);
            app.Step1LOADTHETARGETIMAGELabel_7.Text = 'click "Validate tiles size".';
            app.Step1LOADTHETARGETIMAGELabel_8 = uilabel(app.Panel);
            app.Step1LOADTHETARGETIMAGELabel_8.Text = 'Step 4 : Select your matching strategy in ';
            app.Step1LOADTHETARGETIMAGELabel_9 = uilabel(app.Panel);
            app.Step1LOADTHETARGETIMAGELabel_9.Text = 'the "Matching" dropdown.';
            app.Step1LOADTHETARGETIMAGELabel_10 = uilabel(app.Panel);
            app.Step1LOADTHETARGETIMAGELabel_10.Text = 'Step 5 (optional) :  Check "Color correct"';
            app.Step1LOADTHETARGETIMAGELabel_11 = uilabel(app.Panel);
            app.Step1LOADTHETARGETIMAGELabel_11.Text = 'to enable color adjustments and adjust ';
            app.Step1LOADTHETARGETIMAGELabel_12 = uilabel(app.Panel);
            app.Step1LOADTHETARGETIMAGELabel_12.Text = '"Blending Strength" to blend the original';
            app.Step1LOADTHETARGETIMAGELabel_13 = uilabel(app.Panel);
            app.Step1LOADTHETARGETIMAGELabel_13.Text = 'image with alpha transparency.';
            app.Step1LOADTHETARGETIMAGELabel_14 = uilabel(app.Panel);
            app.Step1LOADTHETARGETIMAGELabel_14.Text = 'Step 6 :  Click "Generate the mosaic" to ';
            app.Step1LOADTHETARGETIMAGELabel_16 = uilabel(app.Panel);
            app.Step1LOADTHETARGETIMAGELabel_16.Text = 'view the final result!';
            app.GeneratethemosaicButton = uibutton(app.UIFigure, 'push');
            app.GeneratethemosaicButton.ButtonPushedFcn = createCallbackFcn(app, @GeneratethemosaicButtonPushed2, true);
            app.GeneratethemosaicButton.Text = 'Generate the mosaic';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = mosaic_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end